classdef AdeeptArmGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Go3Button              matlab.ui.control.Button
        Go2Button              matlab.ui.control.Button
        Go1Button              matlab.ui.control.Button
        Save3Button            matlab.ui.control.Button
        Save2Button            matlab.ui.control.Button
        Save1Button            matlab.ui.control.Button
        HomeButton             matlab.ui.control.Button
        GoButton               matlab.ui.control.Button
        Joint2Slider           matlab.ui.control.Slider
        Joint2SliderLabel      matlab.ui.control.Label
        Joint3Slider           matlab.ui.control.Slider
        Joint3SliderLabel      matlab.ui.control.Label
        Joint1Slider           matlab.ui.control.Slider
        Joint1SliderLabel      matlab.ui.control.Label
        J3ValueEditField       matlab.ui.control.NumericEditField
        J3ValueEditFieldLabel  matlab.ui.control.Label
        J2ValueEditField       matlab.ui.control.NumericEditField
        J2ValueEditFieldLabel  matlab.ui.control.Label
        J1ValueEditField       matlab.ui.control.NumericEditField
        J1ValueEditFieldLabel  matlab.ui.control.Label
        axes_arm               matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        link1Patch
        link1Vertices
        link2Patch
        link2Vertices
        link3Patch
        link3Vertices
        jointAngles = [0, 0, 0]

        savedAngles1 = [0, 0, 0]
        savedAngles2 = [0, 0, 0]
        savedAngles3 = [0, 0, 0]


    end

    methods (Access = public)

        function updateArm(app)

            % Create the A homogenous transformation matrices for the given jointAngles
            A1 = create_A_matrix(64, 0, 0, app.jointAngles(1));
            A2 = create_A_matrix(0, 0, -90, app.jointAngles(2));
            A3 = create_A_matrix(0, 140, 0, app.jointAngles(3));

            % TODO: Create the A homogeneous transformation matrices for the given jointAngles.

            % TODO: Use the current A matricies to form the T0_n matricies.
            T0_1 = A1;
            T0_2 = A1 * A2;
            T0_3 = A1 * A2 * A3;

            % TODO: Use the current T matricies to transform the original patch vertices
            link1VerticesWRTground = T0_1 * app.link1Vertices;
            link2VerticesWRTground = T0_2 * app.link2Vertices;
            link3VerticesWRTground = T0_3 * app.link3Vertices;

            % TODO: Update the patches with the newly transformed vertices
            set(app.link1Patch,'Vertices', link1VerticesWRTground(1:3,:)');
            set(app.link2Patch,'Vertices', link2VerticesWRTground(1:3,:)');
            set(app.link3Patch,'Vertices', link3VerticesWRTground(1:3,:)');


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
  
            % Begin: Code that can go into your GUI's StartupFcn.
            clc

            % Prepare the axes_arm properties
            app.axes_arm.View = [90 -50];
            app.axes_arm.Visible = 'off';

            % Once you are in Matlab App Designer you can try these axes_arm properties too.
            % These properties keep the Axes from automatically resizing (optional)
            % app.axes_arm.XLim = [-160 250];
            % app.axes_arm.YLim = [-250 250];
            % app.axes_arm.ZLim = [-22 22];

            % Create vertices for all the patches
            makeStlLink("AdeeptL0.stl", app.axes_arm, [.2 .2 .2]);  % Doesn't move. No need to save a handle to it.
            % Save references to the vertices of each patch, make points 4x1 not 3x1.
            app.link1Patch = makeStlLink("AdeeptL1.stl", app.axes_arm, [112/255 198/255 210/255]);
            app.link1Vertices = get(app.link1Patch, 'Vertices')';
            app.link1Vertices(4,:) = ones(1, size(app.link1Vertices,2));

            app.link2Patch = makeStlLink("AdeeptL2.stl", app.axes_arm, [198/255 172/255 20/255]);
            app.link2Vertices = get(app.link2Patch, 'Vertices')';
            app.link2Vertices(4,:) = ones(1, size(app.link2Vertices,2));

            app.link3Patch = makeStlLink("AdeeptL3.stl", app.axes_arm, [181/255 24/255 35/255]);
            app.link3Vertices = get(app.link3Patch, 'Vertices')';
            app.link3Vertices(4,:) = ones(1, size(app.link3Vertices,2));
            app.updateArm();
        end

        % Value changed function: Joint1Slider
        function Joint1SliderValueChanged(app, event)
            value = app.Joint1Slider.Value;
            app.jointAngles(1) = value;

            app.J1ValueEditField.Value = value;
            app.updateArm()

        end

        % Value changed function: Joint2Slider
        function Joint2SliderValueChanged(app, event)
            value = app.Joint2Slider.Value;
            app.jointAngles(2) = value;

            app.J2ValueEditField.Value = value;
            app.updateArm()
        end

        % Value changed function: Joint3Slider
        function Joint3SliderValueChanged(app, event)
            value = app.Joint3Slider.Value;
            app.jointAngles(3) = value;

            app.J3ValueEditField.Value = value;
            app.updateArm()
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            value1 = app.J1ValueEditField.Value;
            value2 = app.J2ValueEditField.Value;
            value3 = app.J1ValueEditField.Value;
            app.Joint1Slider.Value = value1;
            app.Joint2Slider.Value = value2;
            app.Joint3Slider.Value = value3;
            app.jointAngles(1) = value1;
            app.jointAngles(2) = value2;
            app.jointAngles(3) = value3;
            app.updateArm()
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            value = 0;

            app.Joint1Slider.Value = value;
            app.Joint2Slider.Value = value;
            app.Joint3Slider.Value = value;

            app.J1ValueEditField.Value = value;
            app.J2ValueEditField.Value = value;
            app.J3ValueEditField.Value = value;

            app.jointAngles(1) = value;
            app.jointAngles(2) = value;
            app.jointAngles(3) = value;

            app.updateArm()
        end

        % Button pushed function: Save1Button
        function Save1ButtonPushed(app, event)
            value1 = app.J1ValueEditField.Value;
            value2 = app.J2ValueEditField.Value;
            value3 = app.J1ValueEditField.Value;

            app.savedAngles1 = [value1, value2, value3];
        end

        % Button pushed function: Save2Button
        function Save2ButtonPushed(app, event)
            value1 = app.J1ValueEditField.Value;
            value2 = app.J2ValueEditField.Value;
            value3 = app.J1ValueEditField.Value;

            app.savedAngles2 = [value1, value2, value3];
        end

        % Button pushed function: Save3Button
        function Save3ButtonPushed(app, event)
            value1 = app.J1ValueEditField.Value;
            value2 = app.J2ValueEditField.Value;
            value3 = app.J1ValueEditField.Value;

            app.savedAngles3 = [value1, value2, value3];
        end

        % Button pushed function: Go1Button
        function Go1ButtonPushed(app, event)
            value1 = app.savedAngles1(1);
            value2 = app.savedAngles1(2);
            value3 = app.savedAngles1(3);
            
            app.Joint1Slider.Value = value1;
            app.Joint2Slider.Value = value2;
            app.Joint3Slider.Value = value3;

            app.J1ValueEditField.Value = value1;
            app.J2ValueEditField.Value = value2;
            app.J3ValueEditField.Value = value3;

            app.jointAngles(1) = value1;
            app.jointAngles(2) = value2;
            app.jointAngles(3) = value3;

            app.updateArm()
        end

        % Button pushed function: Go2Button
        function Go2ButtonPushed(app, event)
            value1 = app.savedAngles2(1);
            value2 = app.savedAngles2(2);
            value3 = app.savedAngles3(3);
            
            app.Joint1Slider.Value = value1;
            app.Joint2Slider.Value = value2;
            app.Joint3Slider.Value = value3;

            app.J1ValueEditField.Value = value1;
            app.J2ValueEditField.Value = value2;
            app.J3ValueEditField.Value = value3;

            app.jointAngles(1) = value1;
            app.jointAngles(2) = value2;
            app.jointAngles(3) = value3;

            app.updateArm()
        end

        % Button pushed function: Go3Button
        function Go3ButtonPushed(app, event)
            value1 = app.savedAngles3(1);
            value2 = app.savedAngles3(2);
            value3 = app.savedAngles3(3);
            
            app.Joint1Slider.Value = value1;
            app.Joint2Slider.Value = value2;
            app.Joint3Slider.Value = value3;

            app.J1ValueEditField.Value = value1;
            app.J2ValueEditField.Value = value2;
            app.J3ValueEditField.Value = value3;

            app.jointAngles(1) = value1;
            app.jointAngles(2) = value2;
            app.jointAngles(3) = value3;

            app.updateArm()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axes_arm = uiaxes(app.UIFigure);
            title(app.axes_arm, 'axes_arm')
            xlabel(app.axes_arm, 'X')
            ylabel(app.axes_arm, 'Y')
            zlabel(app.axes_arm, 'Z')
            app.J1ValueEditFieldLabel = uilabel(app.UIFigure);
            app.J1ValueEditFieldLabel.Text = 'J1 Value';
            app.J1ValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.J2ValueEditFieldLabel = uilabel(app.UIFigure);
            app.J2ValueEditFieldLabel.Text = 'J2 Value';
            app.J2ValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.J3ValueEditFieldLabel = uilabel(app.UIFigure);
            app.J3ValueEditFieldLabel.Text = 'J3 Value';
            app.J3ValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.Limits = [-90 90];
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderValueChanged, true);
            app.Joint3SliderLabel = uilabel(app.UIFigure);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint3Slider.Limits = [-90 90];
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @Joint3SliderValueChanged, true);
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.Limits = [-90 90];
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderValueChanged, true);
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go Button';
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home Button';
            app.Save1Button = uibutton(app.UIFigure, 'push');
            app.Save1Button.ButtonPushedFcn = createCallbackFcn(app, @Save1ButtonPushed, true);
            app.Save1Button.Text = 'Save 1';
            app.Save2Button = uibutton(app.UIFigure, 'push');
            app.Save2Button.ButtonPushedFcn = createCallbackFcn(app, @Save2ButtonPushed, true);
            app.Save2Button.Text = 'Save 2';
            app.Save3Button = uibutton(app.UIFigure, 'push');
            app.Save3Button.ButtonPushedFcn = createCallbackFcn(app, @Save3ButtonPushed, true);
            app.Save3Button.Text = 'Save 3';
            app.Go1Button = uibutton(app.UIFigure, 'push');
            app.Go1Button.ButtonPushedFcn = createCallbackFcn(app, @Go1ButtonPushed, true);
            app.Go1Button.Text = 'Go 1';
            app.Go2Button = uibutton(app.UIFigure, 'push');
            app.Go2Button.ButtonPushedFcn = createCallbackFcn(app, @Go2ButtonPushed, true);
            app.Go2Button.Text = 'Go 2';
            app.Go3Button = uibutton(app.UIFigure, 'push');
            app.Go3Button.ButtonPushedFcn = createCallbackFcn(app, @Go3ButtonPushed, true);
            app.Go3Button.Text = 'Go 3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdeeptArmGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end