classdef BYOB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TipLabel               matlab.ui.control.Label
        Saved3Label            matlab.ui.control.Label
        Saved2Label            matlab.ui.control.Label
        Saved1Label            matlab.ui.control.Label
        TraceCheckBox          matlab.ui.control.CheckBox
        TraceColorButton       matlab.ui.control.Button
        ClearTraceButton       matlab.ui.control.Button
        AnimateCheckBox        matlab.ui.control.CheckBox
        J4ValueEditField       matlab.ui.control.NumericEditField
        J4ValueEditFieldLabel  matlab.ui.control.Label
        Joint4Slider           matlab.ui.control.Slider
        Joint4SliderLabel      matlab.ui.control.Label
        Go3Button              matlab.ui.control.Button
        Go2Button              matlab.ui.control.Button
        Go1Button              matlab.ui.control.Button
        Save3Button            matlab.ui.control.Button
        Save2Button            matlab.ui.control.Button
        Save1Button            matlab.ui.control.Button
        HomeButton             matlab.ui.control.Button
        GoButton               matlab.ui.control.Button
        Joint2Slider           matlab.ui.control.Slider
        Joint2SliderLabel      matlab.ui.control.Label
        Joint3Slider           matlab.ui.control.Slider
        Joint3SliderLabel      matlab.ui.control.Label
        Joint1Slider           matlab.ui.control.Slider
        Joint1SliderLabel      matlab.ui.control.Label
        J3ValueEditField       matlab.ui.control.NumericEditField
        J3ValueEditFieldLabel  matlab.ui.control.Label
        J2ValueEditField       matlab.ui.control.NumericEditField
        J2ValueEditFieldLabel  matlab.ui.control.Label
        J1ValueEditField       matlab.ui.control.NumericEditField
        J1ValueEditFieldLabel  matlab.ui.control.Label
        axes_arm               matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        link1Patch
        link1Vertices
        link2Patch
        link2Vertices
        link3Patch
        link3Vertices
        link4Patch
        link4Vertices
        jointAngles = [0, 0, 0, 0]

        savedAngles1 = [0, 0, 0, 0]
        savedAngles2 = [0, 0, 0, 0]
        savedAngles3 = [0, 0, 0, 0]

        tipMarker
        traceLine
        traceColors = {[1 .45 0], [0 .6 1], [.1 .75 .2], [.85 0 .6], [0 0 0]}
        traceColorIndex = 1
        isAnimating = false
    end

    methods (Access = public)
        function updateArm(app)
            [A1, A2, A3, A4] = create_BYOB_A_matrices(app.jointAngles);

            T0_1 = A1;
            T0_2 = A1 * A2;
            T0_3 = A1 * A2 * A3;
            T0_4 = A1 * A2 * A3 * A4;

            link1VerticesWRTground = T0_1 * app.link1Vertices;
            link2VerticesWRTground = T0_2 * app.link2Vertices;
            link3VerticesWRTground = T0_3 * app.link3Vertices;
            link4VerticesWRTground = T0_4 * app.link4Vertices;

            set(app.link1Patch,'Vertices', link1VerticesWRTground(1:3,:)');
            set(app.link2Patch,'Vertices', link2VerticesWRTground(1:3,:)');
            set(app.link3Patch,'Vertices', link3VerticesWRTground(1:3,:)');
            set(app.link4Patch,'Vertices', link4VerticesWRTground(1:3,:)');

            ptTipOnLink4 = [0;0;0;1];
            tipWRTground = T0_4 * ptTipOnLink4;

            set(app.tipMarker, 'XData', tipWRTground(1), ...
                'YData', tipWRTground(2), 'ZData', tipWRTground(3));

            if app.TraceCheckBox.Value
                set(app.traceLine, ...
                    'XData', [app.traceLine.XData, tipWRTground(1)], ...
                    'YData', [app.traceLine.YData, tipWRTground(2)], ...
                    'ZData', [app.traceLine.ZData, tipWRTground(3)]);
            end
            app.TipLabel.Text = sprintf('Tip:  x = %.1f   y = %.1f   z = %.1f  mm', ...
                tipWRTground(1), tipWRTground(2), tipWRTground(3));
        end
            function setControls(app, angles)
            app.Joint1Slider.Value = angles(1);
            app.Joint2Slider.Value = angles(2);
            app.Joint3Slider.Value = angles(3);
            app.Joint4Slider.Value = angles(4);

            app.J1ValueEditField.Value = angles(1);
            app.J2ValueEditField.Value = angles(2);
            app.J3ValueEditField.Value = angles(3);
            app.J4ValueEditField.Value = angles(4);
            end

            function goToAngles(app, targetAngles)
            if app.isAnimating
                return 
            end

            if app.AnimateCheckBox.Value
                app.isAnimating = true;
                startAngles = app.jointAngles;
                biggestMove = max(abs(targetAngles - startAngles));
                numSteps = max(2, ceil(biggestMove / 2));  % about 2 degrees per step
                for k = 1:numSteps
                    fraction = k / numSteps;
                    app.jointAngles = startAngles + (targetAngles - startAngles) * fraction;
                    app.setControls(app.jointAngles);
                    app.updateArm();
                    drawnow limitrate
                    pause(0.01);
                end
                app.isAnimating = false;
            end

            app.jointAngles = targetAngles;
            app.setControls(app.jointAngles);
            app.updateArm();
        end

        function showSaved(app)
            app.Saved1Label.Text = sprintf('[%g, %g, %g, %g]', round(app.savedAngles1));
            app.Saved2Label.Text = sprintf('[%g, %g, %g, %g]', round(app.savedAngles2));
            app.Saved3Label.Text = sprintf('[%g, %g, %g, %g]', round(app.savedAngles3));
        end
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            appFolder = fileparts(mfilename('fullpath'));
            
            app.axes_arm.View = [35 20];
            app.axes_arm.Visible = 'off';
            hold(app.axes_arm, 'on');

            app.axes_arm.XLim = [-450 450];
            app.axes_arm.YLim = [-450 450];
            app.axes_arm.ZLim = [-110 520];

      
            patch(app.axes_arm, 'XData', [-400 400 400 -400], 'YData', [-400 -400 400 400], ...
                'ZData', -100*ones(1,4), 'FaceColor', [.93 .93 .96], 'EdgeColor', [.8 .8 .85]);

            makeStlLink(fullfile(appFolder, "BYOB_L0.stl"), app.axes_arm, [.25 .25 .25]); 
            app.link1Patch = makeStlLink(fullfile(appFolder, "BYOB_L1.stl"), app.axes_arm, [112/255 198/255 210/255]);
            app.link1Vertices = get(app.link1Patch, 'Vertices')';
            app.link1Vertices(4,:) = ones(1, size(app.link1Vertices,2));

            app.link2Patch = makeStlLink(fullfile(appFolder, "BYOB_L2.stl"), app.axes_arm, [198/255 172/255 20/255]);
            app.link2Vertices = get(app.link2Patch, 'Vertices')';
            app.link2Vertices(4,:) = ones(1, size(app.link2Vertices,2));

            app.link3Patch = makeStlLink(fullfile(appFolder, "BYOB_L3.stl"), app.axes_arm, [181/255 24/255 35/255]);
            app.link3Vertices = get(app.link3Patch, 'Vertices')';
            app.link3Vertices(4,:) = ones(1, size(app.link3Vertices,2));

            app.link4Patch = makeStlLink(fullfile(appFolder, "BYOB_L4.stl"), app.axes_arm, [122/255 63/255 181/255]);
            app.link4Vertices = get(app.link4Patch, 'Vertices')';
            app.link4Vertices(4,:) = ones(1, size(app.link4Vertices,2));

            set([app.link1Patch app.link2Patch app.link3Patch app.link4Patch], 'EdgeColor', 'none');
            light('Parent', app.axes_arm, 'Position', [500 -600 900]);
            lighting(app.axes_arm, 'gouraud');

            app.traceLine = plot3(app.axes_arm, NaN, NaN, NaN, '-', ...
                'Color', app.traceColors{app.traceColorIndex}, 'LineWidth', 2.5);
            app.tipMarker = plot3(app.axes_arm, 0, 0, 0, 'o', 'MarkerSize', 8, ...
                'MarkerFaceColor', [1 .45 0], 'MarkerEdgeColor', 'k');

            app.showSaved();
            app.updateArm();
        end

        % Value changed function: Joint1Slider
        function Joint1SliderValueChanged(app, event)
            value = app.Joint1Slider.Value;
            app.jointAngles(1) = value;
            app.J1ValueEditField.Value = value;
            app.updateArm()
        end

        % Value changed function: Joint2Slider
        function Joint2SliderValueChanged(app, event)
            value = app.Joint2Slider.Value;
            app.jointAngles(2) = value;
            app.J2ValueEditField.Value = value;
            app.updateArm()
        end

        % Value changed function: Joint3Slider
        function Joint3SliderValueChanged(app, event)
            value = app.Joint3Slider.Value;
            app.jointAngles(3) = value;
            app.J3ValueEditField.Value = value;
            app.updateArm()
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            value1 = app.J1ValueEditField.Value;
            value2 = app.J2ValueEditField.Value;
            value3 = app.J3ValueEditField.Value;
            value4 = app.J4ValueEditField.Value;
            app.goToAngles([value1, value2, value3, value4]);
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            app.goToAngles([0, 0, 0, 0]);
        end

        % Button pushed function: Save1Button
        function Save1ButtonPushed(app, event)
            app.savedAngles1 = app.jointAngles;
            app.showSaved();
        end

        % Button pushed function: Save2Button
        function Save2ButtonPushed(app, event)
            app.savedAngles2 = app.jointAngles;
            app.showSaved();
        end

        % Button pushed function: Save3Button
        function Save3ButtonPushed(app, event)
            value1 = app.J1ValueEditField.Value;
            value2 = app.J2ValueEditField.Value;
            value3 = app.J1ValueEditField.Value;

            app.savedAngles3 = [value1, value2, value3];
        end

        % Button pushed function: Go1Button
        function Go1ButtonPushed(app, event)
            app.goToAngles(app.savedAngles1);
        end

        % Button pushed function: Go2Button
        function Go2ButtonPushed(app, event)
            app.goToAngles(app.savedAngles2);
        end

        % Button pushed function: Go3Button
        function Go3ButtonPushed(app, event)
           app.goToAngles(app.savedAngles3);
        end

        % Value changed function: Joint4Slider
        function Joint4SliderValueChanged(app, event)
            value = app.Joint4Slider.Value;
            app.jointAngles(4) = value;
            app.J4ValueEditField.Value = value;
            app.updateArm()
        end

        % Value changing function: Joint4Slider
        function Joint4SliderValueChanging(app, event)
            value = event.Value;
            app.jointAngles(4) = value;
            app.J4ValueEditField.Value = value;
            app.updateArm()
        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            value = event.Value;
            app.jointAngles(1) = value;
            app.J1ValueEditField.Value = value;
            app.updateArm()
        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            value = event.Value;
            app.jointAngles(3) = value;
            app.J3ValueEditField.Value = value;
            app.updateArm()
        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            value = event.Value;
            app.jointAngles(2) = value;
            app.J2ValueEditField.Value = value;
            app.updateArm()
        end

        % Button pushed function: ClearTraceButton
        function ClearTraceButtonPushed(app, event)
            set(app.traceLine, 'XData', NaN, 'YData', NaN, 'ZData', NaN);
        end

        % Value changed function: TraceCheckBox
        function TraceCheckBoxValueChanged(app, event)
             set(app.traceLine, ...
                'XData', [app.traceLine.XData, NaN], ...
                'YData', [app.traceLine.YData, NaN], ...
                'ZData', [app.traceLine.ZData, NaN]);
            
        end

        % Button pushed function: TraceColorButton
        function TraceColorButtonPushed(app, event)

            newColor = rand(1, 3);
            app.traceLine.Color = newColor;
            app.TraceColorButton.BackgroundColor = newColor;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axes_arm = uiaxes(app.UIFigure);
            title(app.axes_arm, 'axes_arm')
            xlabel(app.axes_arm, 'X')
            ylabel(app.axes_arm, 'Y')
            zlabel(app.axes_arm, 'Z')
            app.J1ValueEditFieldLabel = uilabel(app.UIFigure);
            app.J1ValueEditFieldLabel.Text = 'J1 Value';
            app.J1ValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.J2ValueEditFieldLabel = uilabel(app.UIFigure);
            app.J2ValueEditFieldLabel.Text = 'J2 Value';
            app.J2ValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.J3ValueEditFieldLabel = uilabel(app.UIFigure);
            app.J3ValueEditFieldLabel.Text = 'J3 Value';
            app.J3ValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.Limits = [-180 180];
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderValueChanged, true);
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint3SliderLabel = uilabel(app.UIFigure);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint3Slider.Limits = [-180 0];
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @Joint3SliderValueChanged, true);
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.Limits = [0 180];
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderValueChanged, true);
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go Button';
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home Button';
            app.Save1Button = uibutton(app.UIFigure, 'push');
            app.Save1Button.ButtonPushedFcn = createCallbackFcn(app, @Save1ButtonPushed, true);
            app.Save1Button.Text = 'Save 1';
            app.Save2Button = uibutton(app.UIFigure, 'push');
            app.Save2Button.ButtonPushedFcn = createCallbackFcn(app, @Save2ButtonPushed, true);
            app.Save2Button.Text = 'Save 2';
            app.Save3Button = uibutton(app.UIFigure, 'push');
            app.Save3Button.ButtonPushedFcn = createCallbackFcn(app, @Save3ButtonPushed, true);
            app.Save3Button.Text = 'Save 3';
            app.Go1Button = uibutton(app.UIFigure, 'push');
            app.Go1Button.ButtonPushedFcn = createCallbackFcn(app, @Go1ButtonPushed, true);
            app.Go1Button.Text = 'Go 1';
            app.Go2Button = uibutton(app.UIFigure, 'push');
            app.Go2Button.ButtonPushedFcn = createCallbackFcn(app, @Go2ButtonPushed, true);
            app.Go2Button.Text = 'Go 2';
            app.Go3Button = uibutton(app.UIFigure, 'push');
            app.Go3Button.ButtonPushedFcn = createCallbackFcn(app, @Go3ButtonPushed, true);
            app.Go3Button.Text = 'Go 3';
            app.Joint4SliderLabel = uilabel(app.UIFigure);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.UIFigure);
            app.Joint4Slider.Limits = [-180 180];
            app.Joint4Slider.ValueChangedFcn = createCallbackFcn(app, @Joint4SliderValueChanged, true);
            app.Joint4Slider.ValueChangingFcn = createCallbackFcn(app, @Joint4SliderValueChanging, true);
            app.J4ValueEditFieldLabel = uilabel(app.UIFigure);
            app.J4ValueEditFieldLabel.Text = 'J4 Value';
            app.J4ValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.AnimateCheckBox = uicheckbox(app.UIFigure);
            app.AnimateCheckBox.Text = 'Animate Moves';
            app.ClearTraceButton = uibutton(app.UIFigure, 'push');
            app.ClearTraceButton.ButtonPushedFcn = createCallbackFcn(app, @ClearTraceButtonPushed, true);
            app.ClearTraceButton.Text = 'Clear Trace';
            app.TraceColorButton = uibutton(app.UIFigure, 'push');
            app.TraceColorButton.ButtonPushedFcn = createCallbackFcn(app, @TraceColorButtonPushed, true);
            app.TraceColorButton.Text = 'New Trace Color';
            app.TraceCheckBox = uicheckbox(app.UIFigure);
            app.TraceCheckBox.ValueChangedFcn = createCallbackFcn(app, @TraceCheckBoxValueChanged, true);
            app.TraceCheckBox.Text = 'Trace Tip';
            app.Saved1Label = uilabel(app.UIFigure);
            app.Saved1Label.Text = '';
            app.Saved2Label = uilabel(app.UIFigure);
            app.Saved2Label.Text = '';
            app.Saved3Label = uilabel(app.UIFigure);
            app.Saved3Label.Text = '';
            app.TipLabel = uilabel(app.UIFigure);
            app.TipLabel.Text = 'Tip:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BYOB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end