classdef exam1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FlashButton               matlab.ui.control.Button
        PeriondEditField          matlab.ui.control.NumericEditField
        PeriondEditFieldLabel     matlab.ui.control.Label
        NumFlashesEditField       matlab.ui.control.NumericEditField
        NumFlashesEditFieldLabel  matlab.ui.control.Label
        responceLabel             matlab.ui.control.Label
        LEDOffButton              matlab.ui.control.Button
        LEDOnButton               matlab.ui.control.Button
    end

    
    properties (Access = public)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LEDOnButton
        function LEDOnButtonPushed(app, event)
            app.responceLabel.Text = webread("http://137.112.201.20:5000/api/led/on");
        end

        % Button pushed function: LEDOffButton
        function LEDOffButtonPushed(app, event)
            app.responceLabel.Text = webread("http://137.112.201.20:5000/api/led/off");
        end

        % Button pushed function: FlashButton
        function FlashButtonPushed(app, event)
            app.responceLabel.Text = webread("http://137.112.201.20:5000/api/flash/" + string(app.NumFlashesEditField.Value) + "/" + string(app.PeriondEditField.Value));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LEDOnButton = uibutton(app.UIFigure, 'push');
            app.LEDOnButton.ButtonPushedFcn = createCallbackFcn(app, @LEDOnButtonPushed, true);
            app.LEDOnButton.Text = 'LED On';
            app.LEDOffButton = uibutton(app.UIFigure, 'push');
            app.LEDOffButton.ButtonPushedFcn = createCallbackFcn(app, @LEDOffButtonPushed, true);
            app.LEDOffButton.Text = 'LED Off';
            app.responceLabel = uilabel(app.UIFigure);
            app.responceLabel.Text = 'responce';
            app.NumFlashesEditFieldLabel = uilabel(app.UIFigure);
            app.NumFlashesEditFieldLabel.Text = 'Num Flashes';
            app.NumFlashesEditField = uieditfield(app.UIFigure, 'numeric');
            app.PeriondEditFieldLabel = uilabel(app.UIFigure);
            app.PeriondEditFieldLabel.Text = 'Periond';
            app.PeriondEditField = uieditfield(app.UIFigure, 'numeric');
            app.FlashButton = uibutton(app.UIFigure, 'push');
            app.FlashButton.ButtonPushedFcn = createCallbackFcn(app, @FlashButtonPushed, true);
            app.FlashButton.Text = 'Flash';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exam1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end