classdef exam1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FlashButton               matlab.ui.control.Button
        periodEditField           matlab.ui.control.NumericEditField
        periodEditFieldLabel      matlab.ui.control.Label
        numflashesEditField       matlab.ui.control.NumericEditField
        numflashesEditFieldLabel  matlab.ui.control.Label
        responseLabel             matlab.ui.control.Label
        LEDOffButton              matlab.ui.control.Button
        LEDOnButton               matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LEDOnButton
        function LEDOnButtonPushed(app, event)
            app.responseLabel.Text = webread("http://137.112.199.202:5001/api/led/on");
        end

        % Button pushed function: LEDOffButton
        function LEDOffButtonPushed(app, event)
            app.responseLabel.Text = webread("http://137.112.199.202:5001/api/led/off");
        end

        % Button pushed function: FlashButton
        function FlashButtonPushed(app, event)
            app.responseLabel.Text = webread("http://137.112.199.202:5001/api/flash/" + string(app.numflashesEditField.Value) + "/" + string(app.periodEditField.Value));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LEDOnButton = uibutton(app.UIFigure, 'push');
            app.LEDOnButton.ButtonPushedFcn = createCallbackFcn(app, @LEDOnButtonPushed, true);
            app.LEDOnButton.Text = 'LED On';
            app.LEDOffButton = uibutton(app.UIFigure, 'push');
            app.LEDOffButton.ButtonPushedFcn = createCallbackFcn(app, @LEDOffButtonPushed, true);
            app.LEDOffButton.Text = 'LED Off';
            app.responseLabel = uilabel(app.UIFigure);
            app.responseLabel.Text = 'response';
            app.numflashesEditFieldLabel = uilabel(app.UIFigure);
            app.numflashesEditFieldLabel.Text = 'num flashes';
            app.numflashesEditField = uieditfield(app.UIFigure, 'numeric');
            app.periodEditFieldLabel = uilabel(app.UIFigure);
            app.periodEditFieldLabel.Text = 'period';
            app.periodEditField = uieditfield(app.UIFigure, 'numeric');
            app.FlashButton = uibutton(app.UIFigure, 'push');
            app.FlashButton.ButtonPushedFcn = createCallbackFcn(app, @FlashButtonPushed, true);
            app.FlashButton.Text = 'Flash';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exam1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end